classdef bwgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GuassianfilterSpinner       matlab.ui.control.Spinner
        GuassianfilterSpinnerLabel  matlab.ui.control.Label
        BandpassmaxSpinner          matlab.ui.control.Spinner
        BandpassmaxSpinnerLabel     matlab.ui.control.Label
        BandpassminSpinner          matlab.ui.control.Spinner
        ApplyButton                 matlab.ui.control.Button
        BandpassminSpinnerLabel     matlab.ui.control.Label
        ImageAxes3                  matlab.ui.control.UIAxes
        ImageAxes2                  matlab.ui.control.UIAxes
        ImageAxes1                  matlab.ui.control.UIAxes
    end


    properties (Access = public)
        im1 % Raw image
        im2
        im3
        bpmin
        bpmax
        gaussSigma
    end

    methods (Access = private)

        function bw = getbw(app, img)
            [bpimg, ~] = bandpassfilt(img, app.bpmin, app.bpmax);
            bw = imbinarize(imgaussfilt(bpimg, app.gaussSigma), 'adaptive');
            bw = bwareaopen(bw, 8);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img)

            %             f = Nd2Reader(filename);
            %             frameStack = postInfo.frames{1};
            %             switch contrastMethod
            %                 case 0
            %                     lowhigh = [0 1];
            %                 case 1
            %                     lowhigh = postInfo.autoContrastPara{1};
            %                 case 2
            %                     lowhigh = postInfo.manualContrastPara{1};
            %                 otherwise
            %                     error('Variable contrastMethod are unavailable.');
            %             end
            app.im1 = img(:,:,1);
            app.im2 = img(:,:,round(end/2));
            app.im3 = img(:,:,end);

            %             app.im1 = imadjust(f.getimage(frameStack(1)), lowhigh);
            %             app.im2 = imadjust(f.getimage(frameStack(round(end/2))), lowhigh);
            %             app.im3 = imadjust(f.getimage(frameStack(end)), lowhigh);
            %             f.close();

            %             default value
            app.bpmax = 6;
            app.bpmin = 1;
            app.gaussSigma = 1;

            axis(app.ImageAxes1, 'image');
            axis(app.ImageAxes2, 'image');
            axis(app.ImageAxes3, 'image');
            disableDefaultInteractivity(app.ImageAxes1);  % Fix bug of 4-arror pointer outside of roi. Fix at 2022b according to Mathwork.
            disableDefaultInteractivity(app.ImageAxes2);
            disableDefaultInteractivity(app.ImageAxes3);

            %             imshow(app.im1, 'Parent', app.ImageAxes1);
            %             imshow(app.im2, 'Parent', app.ImageAxes2);
            %             imshow(app.im3, 'Parent', app.ImageAxes3);
            imshow(imadjust(app.im1), 'Parent', app.ImageAxes1);
            imshow(imadjust(app.im2), 'Parent', app.ImageAxes2);
            imshow(imadjust(app.im3), 'Parent', app.ImageAxes3);
            %             uiwait(app.UIFigure);
        end

        % Value changed function: BandpassmaxSpinner
        function BandpassmaxSpinnerValueChanged(app, event)
            app.bpmax = app.BandpassmaxSpinner.Value;

        end

        % Value changed function: BandpassminSpinner
        function BandpassminSpinnerValueChanged(app, event)
            app.bpmin = app.BandpassminSpinner.Value;
            %             rotatedImage1 = imrotate(app.im1, app.rotateAngle,'bicubic','crop');
            %             rotatedImage2 = imrotate(app.im2, app.rotateAngle,'bicubic','crop');
            %             rotatedImage3 = imrotate(app.im3, app.rotateAngle,'bicubic','crop');
            %             app.resetroi();
            %             imshow(rotatedImage1, 'Parent', app.ImageAxes1);
            %             imshow(rotatedImage2, 'Parent', app.ImageAxes2);
            %             imshow(rotatedImage3, 'Parent', app.ImageAxes3);
        end

        % Value changed function: GuassianfilterSpinner
        function GuassianfilterSpinnerValueChanged(app, event)
            app.gaussSigma = app.GuassianfilterSpinner.Value;

        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            %             app.TextArea.Value = 'Processing...';
            f = waitbar(0,'Please wait...');
            bw1 = getbw(app, app.im1);
            bw2 = getbw(app, app.im2);
            bw3 = getbw(app, app.im3);
            imshow(bw1, 'Parent', app.ImageAxes1);
            imshow(bw2, 'Parent', app.ImageAxes2);
            imshow(bw3, 'Parent', app.ImageAxes3);
            waitbar(1,f,'Finishing');
            close(f);
            %             app.Label.Text = 'Finish';
            %             app.resetroi();
            %             app.roi1 = drawrectangle(app.ImageAxes1);
            %             app.roiPosition = app.roi1.Position;
            %             app.roi2 = drawrectangle(app.ImageAxes2, 'Position', app.roi1.Position);
            %             app.roi3 = drawrectangle(app.ImageAxes3, 'Position', app.roi1.Position);
            %             addlistener(app.roi1, 'ROIMoved', @(~,~) app.updateroi1); % check with events(roi)
            %             addlistener(app.roi2, 'ROIMoved', @(~,~) app.updateroi2);
            %             addlistener(app.roi3, 'ROIMoved', @(~,~) app.updateroi3);
            %             uiwait(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAxes1 = uiaxes(app.UIFigure);
            app.ImageAxes1.XTick = [];
            app.ImageAxes1.XTickLabel = {'[ ]'};
            app.ImageAxes1.YTick = [];
            app.ImageAxes2 = uiaxes(app.UIFigure);
            app.ImageAxes2.XTick = [];
            app.ImageAxes2.XTickLabel = {'[ ]'};
            app.ImageAxes2.YTick = [];
            app.ImageAxes3 = uiaxes(app.UIFigure);
            app.ImageAxes3.XTick = [];
            app.ImageAxes3.XTickLabel = {'[ ]'};
            app.ImageAxes3.YTick = [];
            app.BandpassminSpinnerLabel = uilabel(app.UIFigure);
            app.BandpassminSpinnerLabel.Text = 'Bandpass min';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.BandpassminSpinner = uispinner(app.UIFigure);
            app.BandpassminSpinner.ValueChangedFcn = createCallbackFcn(app, @BandpassminSpinnerValueChanged, true);
            app.BandpassminSpinner.Value = 1;
            app.BandpassmaxSpinnerLabel = uilabel(app.UIFigure);
            app.BandpassmaxSpinnerLabel.Text = 'Bandpass max';
            app.BandpassmaxSpinner = uispinner(app.UIFigure);
            app.BandpassmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @BandpassmaxSpinnerValueChanged, true);
            app.BandpassmaxSpinner.Value = 6;
            app.GuassianfilterSpinnerLabel = uilabel(app.UIFigure);
            app.GuassianfilterSpinnerLabel.Text = 'Guassian filter';
            app.GuassianfilterSpinner = uispinner(app.UIFigure);
            app.GuassianfilterSpinner.ValueChangedFcn = createCallbackFcn(app, @GuassianfilterSpinnerValueChanged, true);
            app.GuassianfilterSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bwgui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end